classdef SintonTeff_5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Label                   matlab.ui.control.Label
        EditField               matlab.ui.control.NumericEditField
        EditField_2Label        matlab.ui.control.Label
        EditField_2             matlab.ui.control.NumericEditField
        EditField_3             matlab.ui.control.NumericEditField
        EditField_4Label        matlab.ui.control.Label
        EditField_4             matlab.ui.control.NumericEditField
        DataloadedListBoxLabel  matlab.ui.control.Label
        DataloadedListBox       matlab.ui.control.ListBox
        LoadDataButton          matlab.ui.control.Button
        PlotButton              matlab.ui.control.Button
        DeleteButton            matlab.ui.control.Button
        AnalyseDropDownLabel    matlab.ui.control.Label
        AnalyseDropDown         matlab.ui.control.DropDown
        DeleteAllButton         matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CurrDirName; % the name of the current execution directory
        
        % a cell with all the loaded data
        DataCell={'SintonLiftime XY','PathName','FileName','SampleName','datenumber', 'thickness', 'user resistivity', 'calculated res', '1 sunsIvoc', 'wafertype', 'Teff at Specif_DelN'};
        
        % a cell with the analysed SRV data
        SurfParmCell={'SampleName','DelN,S0,S1,S2','DelN,J0s-6Mackel,J0s-Kimmerle','Ndop','TauEff at Specif_DelN','JoeAverage','DeltaN for J0s','iVoc at Specif_DelN','Seff0 at Specif_DelN','Seff2 at Specif_DelN','TauSRHparms,Tn,Tp,Et','Fit resnorm'};
            
        
%         q=1.60e-19;%C electron charge
        epsrox=3.9;%insulator relative permitivity constant
        eps0=8.85e-14;%F/cm vacuum permitivity
        k=1.38e-23;%J/K boltazmann constant
        T=300;%K Temperature
        
        ni=9.65409e9;%1/cm3 Semicon intrinsic carrier consentration,Altermatt JAP 2003 
        phiM=4.1;%eV Metal work function
        epsrs=11.9;% Semicon relative permitivity
        chi=4.1; %V Semicon suceptibility 
        Eg=1.12; %eV Semicon gap energy
        m_e=9.1095e-31;%kg  eletron's mass
        h=6.626068e-34;% m2 kg / s plank's constant
        
    end
    
    methods (Access = private)
        
        function [] = importsintonfile(app,FileName,PathName)
            DataIndex=size(app.DataCell);
            
            if ~contains(FileName,'.xlsm')==0 % sinton version using the Excel sheet
                
               
                [~,~,ReadData]=xlsread(strcat(PathName,FileName),'User','A2:O9','basic');
                
                if strcmp(ReadData{1,12}(1),'3') % 3.2 Software version
                    num = xlsread(strcat(PathName,FileName),'Calc','I11:L131','basic');
                    app.DataCell(DataIndex(1)+1,1)={[num(:,1),num(:,4)]};
                elseif strcmp(ReadData{1,12}(1),'4') || strcmp(ReadData{1,14}(1),'4')% 4 Software version
                    num = xlsread(strcat(PathName,FileName),'RawData','E4:G124','basic');   %% version 4.6 
                    app.DataCell(DataIndex(1)+1,1)={[num(:,3),num(:,1)]};
                end
                    %PathName,FileName,SampleName,datenumber, thickness, user resistivity, calc res, 1 sunsIvoc, wafertype
                    app.DataCell(DataIndex(1)+1,2:11)={PathName,FileName,ReadData{5,1},x2mdate(sum(cell2mat(ReadData(5,9:10)))),ReadData{5,2},ReadData{5,3},ReadData{8,3},ReadData{8,11}, ReadData{5,4},...
                        mean(app.DataCell{DataIndex(1)+1,1}((abs(app.DataCell{DataIndex(1)+1,1}(:,1)-1e15))<1e14,2))};
                    
            elseif ~contains(FileName,'.txt')==0 % sinton version that uses the labview .txt file
           
                %Data cell content=> 'SintonLiftime XY','PathName','FileName','SampleName','datenumber', 'thickness', 'user resistivity', 'calculated res', '1 sunsIvoc', 'wafertype', 'Teff at Specif_DelN'
                   % first lest read the table of data, and extrac tht x-y of lifetime
                    opts = delimitedTextImportOptions("NumVariables", 12);
                    opts.VariableTypes = ["double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double"];
                    opts.DataLines = [32, Inf];
                    opts.Delimiter = "\t";
                    ReadData = readtable(strcat(PathName,FileName), opts);
                    ReadData2 = (table2cell(ReadData));
                    app.DataCell(DataIndex(1)+1,1)={([cell2mat(ReadData2(:,7)),cell2mat(ReadData2(:,5))])}; 
                 
                % then lets get the values measured in the first 30 lines.    
                
                    opts = delimitedTextImportOptions("NumVariables", 2);
                    opts.DataLines = [1, 30];
                    opts.Delimiter = "\t";
                    ReadData = readtable(strcat(PathName,FileName), opts);
                    ReadData = table2cell(ReadData);
                    
            if str2double(ReadData{7,2})==1
                Sitype='n';
            else 
                Sitype='p';
            end
            
            fileprops=dir(strcat(PathName,FileName)); %extract modification date
            
            app.DataCell(DataIndex(1)+1,2:11)={PathName,FileName,ReadData{2,2},datenum(fileprops.date),str2double(ReadData{4,2}),str2double(ReadData{5,2}),str2double(ReadData{15,2}),str2double(ReadData{26,2}), Sitype,...
                         mean(app.DataCell{DataIndex(1)+1,1}((abs(app.DataCell{DataIndex(1)+1,1}(:,1)-1e15))<1e14,2))};
                        
            end
        end


        function ExtractSurfParms(app,DeltaN,TauEff,W,Resistivity, Sitype, PlotsActive,DeltaNkey, SampleName,SRHfitOptions)
            
            % This Function extracts all the recombination parameters from measurements
            % of effective lifetime using a Sinton Instrument, and plots these
            % parameters as a funciont of minority carrier density
            
            % Input variables
            % DeltaN: Column vector with the carrier excess density [cm-3]
            % TauEff: Column vector with effective lifetime data corresponding to
            % excess density in DeltaN in [s]
            % W: wafer thickness in [cm]
            % Resistivity in ohm.cm
            % Si type: either 'n' or 'p'
            % Plots Active: Set to 1 for plotting of variables
            % DeltaNkey: Minority carrier density at which Seff and iVoc are calculated [cm-3]

            q=1.6e-19;
            Vt=(1.38e-23*300)/q; 
            
            load('SiliconData3.mat') %#ok<*LOAD> 
            if strcmp(Sitype,'n'); ResTable=nres;diffusionTable=diffusionN;
            elseif strcmp(Sitype,'p');ResTable=pres; diffusionTable=diffusionP;
            else error('Type of substrate not well defined');end
            
            Ndop = interp1(ResTable(:,2),ResTable(:,1),Resistivity,'linear','extrap');
            
            %extract edges of measurement as tend to have artifacts
            ReIndex=length(DeltaN);
            DeltaN=DeltaN(ceil(ReIndex*0.002):ceil(ReIndex*0.95));
            TauEff=TauEff(ceil(ReIndex*0.002):ceil(ReIndex*0.95));
            
            %interpolate to filter for noise and accelerate fits
            DeltaN2=logspace(log10(min(DeltaN)),log10(max(DeltaN)),60)';
            TauEff2=interp1(DeltaN,TauEff,DeltaN2,'pchip','extrap');
            DeltaN3=logspace(log10(min(DeltaN2)),log10(max(DeltaN2)),30)';
            TauEff2=interp1(DeltaN2,TauEff2,DeltaN3,'pchip','extrap');
            DeltaN2=DeltaN3;
            
            %calculate the intrinsic lifetime of this speciment using Richter's param
            [TauRad,TauAuger]=IntBulkLifetime(app,Ndop,DeltaN2,W);
            
            %find the eff intrinsic carrier density ni as function of injection
            %excess concentration in stable region
            if strcmp(Sitype,'n')
                n_d=-Ndop+DeltaN2;p_d=0+DeltaN2;
            elseif strcmp(Sitype,'p')
                n_d=0+DeltaN2;p_d=Ndop+DeltaN2;
            end

            % Values from Altermatt in NUSOD'2005
            Bmin=0.2+(0-0.2)/(1+(app.T/320).^(2.5));
            b1=2*(1.5e18+(1e7-1.5e18)/(1+(app.T/550).^(3.0)));
            b3=2*(4e18+(1e9-4e18)/(1+(app.T/365).^(3.54)));
            Brel=Bmin+(1.00-Bmin)./(1+((n_d+p_d)/b1).^(0.54)+((n_d+p_d)/b3).^(1.25));
            
            % recalculating for bandgap narrowing
            nieff2=sqrt(app.ni.^2./Brel);
            
            
            %find amb diff coefficient as function of injection
            dummy=permute(diffusionTable,[1 3 2]);
            [X,Y]=meshgrid(diffusionTable(2:202,1,1),dummy(1,:,1));
            [Xq,Yq]=meshgrid(DeltaN2,abs(Ndop));
            Damb2=interp2(X,Y,dummy(2:202,:,9)',Xq,Yq,'linear')';
            
            % SRV with infinite SRH lifetime
            Seff=sqrt(Damb2.*(1./TauEff2-1./TauRad-1./TauAuger)).*tan((W/2)*sqrt((1./Damb2).*(1./TauEff2-1./TauRad-1./TauAuger)));
            
            % Approx 6 Mackel pip.2167
            Joe6=W*q*nieff2.^2.*gradient(1./TauEff2-1./TauRad-1./TauAuger)./gradient(DeltaN2);
            
            % least squares method
            options = optimoptions(@lsqnonlin,'Display','off','MaxFunctionEvaluations',1e6,...
                'FunctionTolerance',1e-12,'MaxIterations',1e3,'StepTolerance',1e-12,...
                'FiniteDifferenceType','central');
            % for the SRH calculation 
            
            %SRH fitting variables TauN, TauP, Et-Ev
            SRH_0=max(1./(1./TauEff2-1./TauRad-1./TauAuger)).*[1 1 0]+[1 1 0.56];%starting point
            SRH_lb=(max(1./(1./TauEff2-1./TauRad-1./TauAuger)))*[0 0 0];%lower boundary
            SRH_ub=[1,1,1.05];
            
            if SRHfitOptions(1)~=0;SRH_0(1)= SRHfitOptions(1);
            SRH_lb(1)= SRHfitOptions(1);SRH_ub(1)=SRHfitOptions(1);end
            
            if SRHfitOptions(2)~=0;SRH_0(2)= SRHfitOptions(2);
            SRH_lb(2)= SRHfitOptions(2);SRH_ub(2)=SRHfitOptions(2);end
            
            if SRHfitOptions(3)~=0; SRH_0(3)= SRHfitOptions(1);
            SRH_lb(3)= SRHfitOptions(3);SRH_ub(3)=SRHfitOptions(3);end


            switch Sitype
                case 'p'; p_0=Ndop;n_0=nieff2.^2./p_0;%1/cm3 equilibrium electrons concentration 
                case 'n'; n_0=-Ndop;p_0=nieff2.^2./n_0;%1/cm3 equilibrium electrons concentration 
            end
            
            errorCode=0;
            % Joe calculated from Kimmerle SOLMAT 142 (2015) 116–122
            for i=1:4% number of times to iterate the algorithm
                
                %calculate J0 with intrinsic bulk lifetime
                Joe2=q*gradient(nieff2.^2.*sqrt(Damb2.*(1./TauEff2-1./TauRad-1./TauAuger-1./(SRHfunction(app,SRH_0(1),SRH_0(2),SRH_0(3),DeltaN2,nieff2,n_0,p_0)))).*...
                        tan((W/2)*sqrt((1./Damb2).*(1./TauEff2-1./TauRad-1./TauAuger-1./(SRHfunction(app,SRH_0(1),SRH_0(2),SRH_0(3),DeltaN2,nieff2,n_0,p_0))))))./gradient(DeltaN2);
            
                Joe2_pos=Joe2(Joe2>0);% only account for positive values of Joe
                DeltaN_pos=DeltaN2(Joe2>0);
                if SRHfitOptions(4)==0
                    
                    if isempty(DeltaN_pos)% when all J0 calculated is negative the method won't work. revert to simple 
                        errorCode=1;
                        errordlg('Surface recombinaiton is so high J_0 must be calculated from Seff',' Error');
                        Joe2=sqrt(Damb2.*(1./TauEff2-1./TauRad-1./TauAuger-1./SRH_0)).*tan((W/2)*sqrt((1./Damb2).*(1./TauEff2-1./TauRad-1./TauAuger-1./SRH_0))).*(q*nieff2.^2)./((abs(Ndop)+DeltaN2));
                        Joe2_pos=Joe2(Joe2>0);% only account for positive values of Joe
                        DeltaN_pos=DeltaN2(Joe2>0);
                    end
            
                    %only analise deta for deltaN>0.5Ndop
                    [~,IndexMinDeltaN]=min(abs(DeltaN_pos-0.5*abs(Ndop)));
            
                    %calculate the gradient of J0 to find flatests section
                    GradJoe2=abs(gradient(Joe2_pos(IndexMinDeltaN:end))./gradient(DeltaN_pos(IndexMinDeltaN:end)));
                    [~,indexJoe]=min(GradJoe2);
                    indexJoe=indexJoe+IndexMinDeltaN;
            
                    if indexJoe<0.1*length(DeltaN_pos); indexJoe=ceil(0.1*length(DeltaN_pos)) ;
                    elseif indexJoe>0.9*length(DeltaN_pos); indexJoe=ceil(0.9*length(DeltaN_pos) );
                    end
                    %take the flates section and make it J0 calculated
                    JoeAverage=mean((Joe2_pos(ceil(indexJoe*0.9):floor(indexJoe*1.1))));
            
                else
                    indexJoe=1;
            %         Joe2=ones(length(DeltaN2),1)*SRHfitOptions(4);
                    JoeAverage=SRHfitOptions(4);
                end


                %x=[TauSRH_n,TauSRH_p, Et-Ev]
                ObjectiveFun2 = @(x)(JoeAverage*(abs(Ndop)+DeltaN2)./(q*nieff2.^2)-...
                    (sqrt(Damb2.*(1./TauEff2-1./TauRad-1./TauAuger-1./(SRHfunction(app,x(1),x(2),x(3),DeltaN2,nieff2,n_0,p_0)))).*...
                    tan((W/2)*sqrt((1./Damb2).*(1./TauEff2-1./TauRad-1./TauAuger-1./(SRHfunction(app,x(1),x(2),x(3),DeltaN2,nieff2,n_0,p_0)))))));
                
                [SRH_I,resnorm]= lsqnonlin(ObjectiveFun2, SRH_0 ,SRH_lb,SRH_ub,options);
                
            %     dummy1=fsolve(ObjectiveFun2,SRH_0);
                SRH_0=SRH_I;
                
                if errorCode==1; break; end %when S is so high these methods is inaccurate
                
            end
            
            SRH_0=SRHfunction(app,SRH_I(1),SRH_I(2),SRH_I(3),DeltaN2,nieff2,n_0,p_0);
            
            iVoc=Vt*log((DeltaN2.*(abs(Ndop)+DeltaN2))./nieff2.^2);
            
            [~,indexDeltaN]=min(abs(DeltaN2-DeltaNkey));
            
            
            % SRV with estimated SRH lifetime using algorithm
            Seff2=sqrt(Damb2.*(1./TauEff2-1./TauRad-1./TauAuger-1./SRH_0)).*tan((W/2)*sqrt((1./Damb2).*(1./TauEff2-1./TauRad-1./TauAuger-1./SRH_0)));
            Seff3=JoeAverage*(abs(Ndop)+DeltaN2)./(q*nieff2.^2);
            Jeo1=Seff.*(q*nieff2.^2)./((abs(Ndop)+DeltaN2));
            
            %%
            
            if PlotsActive
                figure('Name', SampleName);
                
                subplot(2,2,1);
                loglog(DeltaN,TauEff,'o',DeltaN2,1./(1./TauRad+1./TauAuger),'-', ...
                    DeltaN2, 1./(1./TauRad+1./TauAuger+(2*JoeAverage*(abs(Ndop)+DeltaN2)./(W*q*nieff2.^2))+1./SRH_0),'-',...%DeltaN,W./(2*Seff2),'-',...
                    DeltaN2,SRH_0,'-',...
                    DeltaN2,1./(2*JoeAverage*(abs(Ndop)+DeltaN2)./(W*q*nieff2.^2)),'-',...
                    [abs(Ndop) abs(Ndop)],[min(TauEff) max(TauEff)],'--' );
                
                legend({'Exp Data','\tau_{intrinsic}','\tau_{best fit}','\tau_{SRH}','\tau_{SurfaceJ_{0S}}','N_{doping}'},'location','best');
                xlabel('\Deltan [cm^{-3}]');ylabel('\tau (s)');
                xlim([1e13 1e17]);
               
                subplot(2,2,2);
                loglog(DeltaN2,Seff,'o',DeltaN2,Seff2,'-', DeltaN2,Seff3,'.-');
                legend({'S_{eff0} from \tau_{eff} (\tau_{SRH}=\infty)',sprintf('S_{eff1} when finite SRH'),'S_{eff2} from J_{0s-avg}'},'location','best');
                xlabel('\Deltan (cm^{-3})');ylabel('S_{eff} (cm/s)');
                
                subplot(2,2,3);
                loglog(DeltaN2,1e15*(Joe6),'o',DeltaN2,1e15*Joe2,'^',...
                    DeltaN2,1e15*Jeo1,[1e13 1e17],1e15*[JoeAverage JoeAverage]);
                legend({'J_{0s-6} Mackel','Kimmerle','J_{0s} from S_{eff1}','J_{0s} Avg'},'location','best');
                xlabel('\Deltan (cm^{-3})');ylabel('J_{0s} (fA/cm^2)');
                
                subplot(2,2,4);semilogx(DeltaN2,iVoc,'o')
                xlabel('\Deltan (cm^{-3})');ylabel('iV_{oc} (V)');  
            end
            
            % sprintf('Function Results:\nNdop:%1.2e \nTauEff:%1.2e\nJoeAverage:%1.2e\nDeltaN_{Joe}:%1.2e\niVoc:%1.2e\nSeff:%1.2e or %1.2e\nTauSRH:%1.2e',...
            %     Ndop,TauEff(indexDeltaN),JoeAverage,DeltaN_pos(indexJoe,1), iVoc(indexDeltaN), Seff(indexDeltaN),Seff2(indexDeltaN),TauSRH)
            
            %% Asign value to logging cell
            
            DataIndex=size(app.SurfParmCell);
            app.SurfParmCell(DataIndex(1)+1,1:DataIndex(2))={SampleName,[DeltaN2,Seff,Seff2,Seff3],[DeltaN2,Joe6,Joe2],Ndop,TauEff2(indexDeltaN),JoeAverage,DeltaN_pos(indexJoe,1), iVoc(indexDeltaN), Seff(indexDeltaN),Seff2(indexDeltaN),SRH_I,resnorm};
            
            end
            
            function F=SRHfunction(app,taun,taup,Et_Ev,DeltaN,ni,n_0,p_0)
            q=1.6e-19;
            Vt=(1.38e-23*300)/q; 
            
                Et=-(1.1/2-Et_Ev);
                n_1=ni.*exp(Et/Vt);
                p_1=ni.*exp(-Et/Vt);
                F=((taup*(n_0+n_1+DeltaN)+taun*(p_0+p_1+DeltaN))./(p_0+n_0+DeltaN));
            
            end
            function [t_Aug,t_Rad]=IntBulkLifetime(app,Ndop,Delta_n,waferT)
            
                q=1.60e-19;%C electron charge
                k=1.38e-23;%J/K boltazmann constant
                T=300;%K Temperature
                ni=9.65e9;%1/cm3 Semicon intrinsic carrier consentration
                              
                % Niewelt model
                
                %excess concentration in stable region
                if Ndop>0;p_0=Ndop;n_0=ni^2/p_0;% 
                else; n_0=-Ndop; p_0=ni^2/n_0;
                end
                
                if n_0>p_0;n_d=n_0+Delta_n;p_d=0+Delta_n;
                else; n_d=0+Delta_n;p_d=p_0+Delta_n;
                end
                            
                Blow=4.76e-15 ; %[cm-3s-1] Nguyen
                % Values from Altermatt in NUSOD'2005
                Bmin=0.2+(0-0.2)/(1+(T/320).^(2.5));
                b1=2*(1.5e18+(1e7-1.5e18)/(1+(T/550).^(3.0)));
                b3=2*(4e18+(1e9-4e18)/(1+(T/365).^(3.54)));
                Brel=Bmin+(1.00-Bmin)./(1+((n_d+p_d)/b1).^(0.54)+((n_d+p_d)/b3).^(1.25));
                
                % recalculating for bandgap narrowing
                nieff2=ni.^2./Brel;
                if n_0>p_0
                    n0eff=n_0;p0eff=nieff2./n_0;
                    n_d=n_0+Delta_n;
                    p_d=nieff2./n_0+Delta_n;
                else
                    p0eff=p_0;n0eff=nieff2./p_0;
                    n_d=nieff2./p_0+Delta_n;
                    p_d=p_0+Delta_n;
                end
          
                B=Brel.*Blow; %[cm-3s-1] radiative recombination probability
                
                %photon recycling
                fPR= 0.9835 + 0.006841*log10(waferT)-4.554e-9.*(Delta_n).^(0.4612);
                        
                geeh=1+(4.38-1)*(1./(1+((n_d+p_d)/4e17).^(2)));
                gehh=1+(4.88-1)*(1./(1+((n_d+p_d)/4e17).^(2)));
                
                R_Auger=((3.41e-31*geeh.*((n_d.^2.*p_d)-(n0eff.^2.*p0eff))+1.17e-31*gehh.*((n_d.*p_d.^2)-(n0eff.*p0eff.^2))));
                
                t_Aug=(Delta_n)./(R_Auger);
                
                R_rad=((n_d.*p_d-nieff2).*(B.*(1-fPR)));
                
                t_Rad=(Delta_n)./(R_rad-0);
            
            
            end
        
            function Print2Figure(app)
                figure;
                hdlax=axes;
                axis([app.EditField_3.Value app.EditField_4.Value app.EditField.Value app.EditField_2.Value]);
                
                set(hdlax, 'YScale', 'log');
                set(hdlax, 'XScale', 'log');
                xlabel('Injection level \Deltan (cm^{-3})'); 
                ylabel('Effective lifetime (s)');
                lgd=legend('show');
                lgd.Interpreter= 'none';
                lgd.Location= 'none';
                lgd.Position=[0.7 0.7 0.2 0.1];
                lgd.FontSize= 10;
                hold all;
                copyobj(app.UIAxes.Children,hdlax)
                            
            end
            
        
        function plotlifetimemcd(app,SpecifiedMCD, xaxisis, ix)
            global CellTau_SMCD;
            figure;
            
            Tau_SMCD=zeros(length(ix),2);
            CellTau_SMCD=cell(length(ix),3);
            for i=1:length(ix)
                Tau_SMCD(i,1)=app.DataCell{ix(i),5};
                Tau_SMCD(i,2)=interp1(app.DataCell{ix(i),1}(:,1),app.DataCell{ix(i),1}(:,2),SpecifiedMCD,'linear','extrap');
                CellTau_SMCD(i,:)={app.DataCell{ix(i),4},Tau_SMCD(i,1),Tau_SMCD(i,2)};
            end
            
            
            evalin('base','global CellTau_SMCD');
                        open CellTau_SMCD
            
            switch xaxisis
                case 1
                    plot(Tau_SMCD(:,1),Tau_SMCD(:,2),'o');
                    datetick('x', 'dd, mmm, yyyy, HH:MM:SS')
            
                case 2
                    plot((Tau_SMCD(:,1)-min(Tau_SMCD(:,1)))*60*24,Tau_SMCD(:,2),'o');
                    xlabel('Time (min)');
                case 3
                    plot(Tau_SMCD(:,2),'o');
                    xlabel('Meas Number');
            
            end
            
            set(gca, 'YScale', 'log');
            ylabel('Effective lifetime (s)');
            
        end
        
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField, EditField_2, 
        % EditField_3, EditField_4
        function AxesReformat(app, event)
            
            app.UIAxes
            app.EditField_4.Value;
            
            
            app.UIAxes.XLim = [app.EditField_3.Value app.EditField_4.Value];
            app.UIAxes.YLim = [app.EditField.Value app.EditField_2.Value];
            
        end

        % Button pushed function: LoadDataButton
        function LoadData(app, event)
            
            
            try
            
            DataIndex=size(app.DataCell);
            
            button = questdlg('Importing Data from Sinton WCT120 files','Please select','Import File','Import Folder','Cancel','Cancel');
            switch button
                case 'Import File'
            
                    [FileName,PathName,~] = uigetfile({strcat(app.CurrDirName,'*.xlsm;*.txt')});
                    importsintonfile(app,FileName,PathName);
                    app.CurrDirName=PathName;
            
                    IndexArray=DataIndex(1)+1;
                    
                    plot(app.UIAxes,app.DataCell{IndexArray,1}(:,1),app.DataCell{IndexArray,1}(:,2),...
                        'DisplayName',app.DataCell{IndexArray,4},...
                        'LineWidth',2);
            
                    % Add sample to the list
                    inputFileNames = app.DataloadedListBox.Items;
                    inputFileNames{end+1} = app.DataCell{IndexArray,4};
                    app.DataloadedListBox.Items=inputFileNames;
                    app.DataloadedListBox.ItemsData=(2:1:IndexArray)' ;
            
                case 'Import Folder'
            
                    PathName =strcat(uigetdir(app.CurrDirName),'/');
                    listing = dir(PathName);
                    FolderSize = length(listing);
            
                    for i=1:FolderSize
                        if (isempty(strfind(listing(i).name,'.xlsm'))==0 || isempty(strfind(listing(i).name,'.txt'))==0) && isempty(strfind(listing(i).name,'._'))==1 && isempty(strfind(listing(i).name,'~$'))==1
                            FileName=listing(i).name;
                            importsintonfile(app,FileName,PathName);
            
                            IndexArray=DataIndex(1)+1;
                            plot(app.UIAxes,app.DataCell{IndexArray,1}(:,1),app.DataCell{IndexArray,1}(:,2),...
                                'DisplayName',app.DataCell{IndexArray,4},...
                                'LineWidth',2);
                    
                            % Add sample to the list
                            inputFileNames = app.DataloadedListBox.Items;
                            inputFileNames{end+1} = app.DataCell{IndexArray,4};
                            app.DataloadedListBox.Items=inputFileNames;
                            app.DataloadedListBox.ItemsData=(2:1:IndexArray)' ;
                            
                            DataIndex=size(app.DataCell);
                        end
                    end
            
                    app.CurrDirName=PathName;
            
                case 'No thank you'
            end
            
            catch err
                    errordlg(err.message,' Error');
            end
            
            legend(app.UIAxes);
            app.UIAxes.Legend.Position=[0.5 0.5 0.2 0.2];
            app.UIAxes.Legend.Interpreter='none';
            
        
        end

        % Value changed function: AnalyseDropDown
        function AnalyseData(app, event)
            value = app.AnalyseDropDown.Value;

            errCase=0;
            
            try
            
                switch value

                    case 'See raw data' 
                        assignin('base','DataCell',app.DataCell);
                        evalin('base','open DataCell');
                       
            
                    case 'Print figure'
            
                        Print2Figure(app);
            
                    case 'Calculate surface and bulk recombination'
            
                        if isempty(app.DataloadedListBox.Value); errCase=1; end
            
                        for i=1:length(app.DataloadedListBox.Value)
                            IndexArray=app.DataloadedListBox.Value(i);
            
                            DeltaN=app.DataCell{IndexArray,1}(:,1);
                            TauEff=app.DataCell{IndexArray,1}(:,2);
                            W=app.DataCell{IndexArray,6};
                            Resistivity=app.DataCell{IndexArray,7};
                            Sitype=app.DataCell{IndexArray,10};Sitype=Sitype(1);
                            DeltaNkey=1e15;
            
                            SampleName=app.DataloadedListBox.Items{app.DataloadedListBox.Value(i)-1};
            
                            options.Interpreter = 'tex';
                            DlgResults = inputdlg({'Fix value of \tau_{N0} (0 for fit)',...
                                'Fix value of \tau_{P0} (0 for fit)',...
                                'Fix E_t-E_v (0 for fit)',...
                                'Fix value of J_{0S}(0 for fit)',...
                                'Plot (1=active, 0=inactive)','Specify \Deltan'},...
                                'Processing options',[1 50],{'0','0','0.56','0','1','1e15'},options);
            
                            SRHfitOptions=[eval(DlgResults{1}),eval(DlgResults{2}),eval(DlgResults{3}),eval(DlgResults{4})];
            
                            ExtractSurfParms(app,DeltaN,TauEff,W,Resistivity, Sitype, eval(DlgResults{5}),eval(DlgResults{6}),SampleName,SRHfitOptions);
            
                        end
            
                    case 'See surface recombination data'
                        
                        assignin('base','SurfParmCell',app.SurfParmCell);
                        evalin('base','open SurfParmCell');
                        
            
                    case 'Plot lifetime at specified injection'
                        
                        prompt={'Enter \Deltan (cm^{-3})'};
                        name = 'Minority carrier injectrion';
                        defaultans = {'1e15'};
                        options.Interpreter = 'tex';
                        SpecifiedMCD = inputdlg(prompt,name,[1 40],defaultans,options);
                        Xaxisoption = questdlg('Specify x axis for plot', ...
                                    'Independent variable', ...
                                    'Date','Absolute Time','Measurement number','Measurement number');
                        SpecifiedMCD=eval(SpecifiedMCD{1});
                        switch Xaxisoption
                            case 'Date'
                                xaxisis = 1;
                            case 'Absolute Time'
                                xaxisis = 2;
                            case 'Measurement number'
                                xaxisis = 3;
                            otherwise
                                return
                        end
            
                        plotlifetimemcd(app, SpecifiedMCD,xaxisis,app.DataloadedListBox.Value);
                end
            
            
            catch err
                switch errCase
                    case 1
                    errordlg('Please specify one sample to analise',' Error');
                    otherwise
                    errordlg(err.message,' Error');
                end
            end
            
        end

        % Button pushed function: DeleteButton
        function DeletePlots(app, event)
            try
            
                for i=1:length(app.DataloadedListBox.Value)
            
                    IndexArray=app.DataloadedListBox.Value(i);
                    SampleName=app.DataCell{IndexArray,4};
            
                    FigHndls=app.UIAxes.Children;
                    for i=1:length(FigHndls)
                        if strcmp(FigHndls(i).DisplayName,SampleName)
                            delete(FigHndls(i));
                        end
                    end
                end
                catch err
                    errordlg(err.message,' Error');
            end
        end

        % Button pushed function: PlotButton
        function PlotFiles(app, event)
            
            try
            
                for i=1:length(app.DataloadedListBox.Value)
            
                    IndexArray=app.DataloadedListBox.Value(i);
                    plot(app.UIAxes,app.DataCell{IndexArray,1}(:,1),app.DataCell{IndexArray,1}(:,2),...
                        'DisplayName',app.DataCell{IndexArray,4},...
                        'LineWidth',2);
            
                end
                catch err
                    errordlg(err.message,' Error');
            end
        end

        % Button pushed function: DeleteAllButton
        function DeleteAll(app, event)
             
            try
                cla(app.UIAxes);
                app.DataloadedListBox.Items={};
                app.DataloadedListBox.ItemsData=[];
                
                app.DataCell={'SintonLiftime XY','PathName','FileName','SampleName','datenumber', 'thickness', 'user resistivity', 'calculated res', '1 sunsIvoc', 'wafertype', 'Teff at Specif_DelN'};
                app.SurfParmCell={'SampleName','DelN,S0,S1,S2','DelN,J0s-6Mackel,J0s-Kimmerle','Ndop','TauEff at Specif_DelN','JoeAverage','DeltaN for J0s','iVoc at Specif_DelN','Seff0 at Specif_DelN','Seff2 at Specif_DelN','TauSRHparms,Tn,Tp,Et','Fit resnorm'};
            
            catch err
                errordlg(err.message,' Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [1e-09 1];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @AxesReformat, true);
            app.EditField.Value = 1e-06;
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Limits = [1e-09 1];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @AxesReformat, true);
            app.EditField_2.Value = 0.01;
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.Limits = [0 1e+20];
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @AxesReformat, true);
            app.EditField_3.Value = 1000000000000;
            app.EditField_4Label = uilabel(app.UIFigure);
            app.EditField_4Label.Text = '';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.Limits = [0 1e+20];
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @AxesReformat, true);
            app.EditField_4.Value = 1e+18;
            app.DataloadedListBoxLabel = uilabel(app.UIFigure);
            app.DataloadedListBoxLabel.Text = 'Data loaded';
            app.DataloadedListBox = uilistbox(app.UIFigure);
            app.DataloadedListBox.Items = {};
            app.DataloadedListBox.Multiselect = 'on';
            app.DataloadedListBox.Value = {};
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadData, true);
            app.LoadDataButton.Text = 'Load Data';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotFiles, true);
            app.PlotButton.Text = 'Plot';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePlots, true);
            app.DeleteButton.Text = 'Delete';
            app.AnalyseDropDownLabel = uilabel(app.UIFigure);
            app.AnalyseDropDownLabel.Text = 'Analyse:';
            app.AnalyseDropDown = uidropdown(app.UIFigure);
            app.AnalyseDropDown.Items = {' ', 'See raw data', 'Print figure', 'Calculate surface and bulk recombination', 'See surface recombination data', 'Plot lifetime at specified injection'};
            app.AnalyseDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalyseData, true);
            app.AnalyseDropDown.Value = ' ';
            app.DeleteAllButton = uibutton(app.UIFigure, 'push');
            app.DeleteAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAll, true);
            app.DeleteAllButton.Text = 'Delete All';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, '\Deltan (cm^{-3})')
            ylabel(app.UIAxes, '\tau_{eff} (cm^{-3})')
            app.UIAxes.XLim = [1000000000000 1e+18];
            app.UIAxes.YLim = [1e-06 0.01];
            app.UIAxes.XScale = 'log';
            app.UIAxes.YTick = [1e-06 1e-05 0.0001 0.001 0.01];
            app.UIAxes.YScale = 'log';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SintonTeff_5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end